classdef AnatoleFiltrageApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplicationpourlefiltrageLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MoveSystemTab                   matlab.ui.container.Tab
        LigneBPanel_5                   matlab.ui.container.Panel
        rglagebornesupborneinfdesfiltresPanel_2  matlab.ui.container.Panel
        BornesupBP2nmLabel              matlab.ui.control.Label
        MS_LP4                          matlab.ui.control.NumericEditField
        BornesupBP3nmLabel              matlab.ui.control.Label
        MS_SP3                          matlab.ui.control.NumericEditField
        MS_MinLP4                       matlab.ui.control.TextArea
        MinTextAreaLabel_4              matlab.ui.control.Label
        MS_MaxLP4                       matlab.ui.control.TextArea
        MaxTextAreaLabel_4              matlab.ui.control.Label
        MS_MinSP3                       matlab.ui.control.TextArea
        MinTextAreaLabel_3              matlab.ui.control.Label
        MS_MaxSP3                       matlab.ui.control.TextArea
        MaxTextAreaLabel_3              matlab.ui.control.Label
        WidthAnmEditField_3Label_2      matlab.ui.control.Label
        MS_WidthB                       matlab.ui.control.NumericEditField
        PeakBnmEditFieldLabel_2         matlab.ui.control.Label
        MS_PeakB                        matlab.ui.control.NumericEditField
        RotationdesfiltresPanel_4       matlab.ui.container.Panel
        MS_BP3Button                    matlab.ui.control.StateButton
        MS_CP2Spinner                   matlab.ui.control.Spinner
        CP2Spinner_2Label               matlab.ui.control.Label
        MS_BP4Spinner                   matlab.ui.control.Spinner
        BP4Spinner_2Label               matlab.ui.control.Label
        MS_CP2Button                    matlab.ui.control.StateButton
        MS_BP4Button                    matlab.ui.control.StateButton
        MS_BP3Spinner                   matlab.ui.control.Spinner
        BP3Spinner_2Label               matlab.ui.control.Label
        LigneAPanel_4                   matlab.ui.container.Panel
        rglagebornesupborneinfdesfiltresPanel  matlab.ui.container.Panel
        MS_MinLP2                       matlab.ui.control.TextArea
        MinTextAreaLabel_2              matlab.ui.control.Label
        MS_MaxLP2                       matlab.ui.control.TextArea
        MaxTextAreaLabel_2              matlab.ui.control.Label
        MS_MinSP1                       matlab.ui.control.TextArea
        MinTextAreaLabel                matlab.ui.control.Label
        MS_MaxSP1                       matlab.ui.control.TextArea
        MaxTextAreaLabel                matlab.ui.control.Label
        BornesupBP1nmLabel              matlab.ui.control.Label
        MS_SP1                          matlab.ui.control.NumericEditField
        BorneInfBP2nmLabel              matlab.ui.control.Label
        MS_LP2                          matlab.ui.control.NumericEditField
        WidthAnmEditField_2Label        matlab.ui.control.Label
        MS_WidthA                       matlab.ui.control.NumericEditField
        MS_PeakA                        matlab.ui.control.NumericEditField
        PeakAnmEditField_2Label         matlab.ui.control.Label
        RotationdesfiltresPanel_3       matlab.ui.container.Panel
        MS_BP1Button                    matlab.ui.control.StateButton
        MS_CP1Spinner                   matlab.ui.control.Spinner
        CP1Spinner_2Label               matlab.ui.control.Label
        MS_BP2Spinner                   matlab.ui.control.Spinner
        BP2Spinner_2Label               matlab.ui.control.Label
        MS_CP1Button                    matlab.ui.control.StateButton
        MS_BP2Button                    matlab.ui.control.StateButton
        MS_BP1Spinner                   matlab.ui.control.Spinner
        BP1Spinner_2Label               matlab.ui.control.Label
        AXgate2                         matlab.ui.control.UIAxes
        AXgate                          matlab.ui.control.UIAxes
        CalibrationTab                  matlab.ui.container.Tab
        SpectroSwitch                   matlab.ui.control.ToggleSwitch
        SpectroSwitchLabel              matlab.ui.control.Label
        SetSourceLEDButton              matlab.ui.control.Button
        SetBackgroundButton             matlab.ui.control.Button
        LigneBPanel_2                   matlab.ui.container.Panel
        LPBP4nmEditFieldLabel           matlab.ui.control.Label
        calibLP4                        matlab.ui.control.NumericEditField
        SPBP3nmEditFieldLabel           matlab.ui.control.Label
        calibSP3                        matlab.ui.control.NumericEditField
        WidthBnmLabel                   matlab.ui.control.Label
        calibWidthB                     matlab.ui.control.NumericEditField
        PeakBnmEditFieldLabel           matlab.ui.control.Label
        calibPeakB                      matlab.ui.control.NumericEditField
        RotationdesfiltresPanel_2       matlab.ui.container.Panel
        calibBP3Button                  matlab.ui.control.StateButton
        calibCP2Spinner                 matlab.ui.control.Spinner
        CP2SpinnerLabel                 matlab.ui.control.Label
        calibBP4Spinner                 matlab.ui.control.Spinner
        BP4SpinnerLabel                 matlab.ui.control.Label
        calibCP2Button                  matlab.ui.control.StateButton
        calibBP4Button                  matlab.ui.control.StateButton
        calibBP3Spinner                 matlab.ui.control.Spinner
        BP3SpinnerLabel                 matlab.ui.control.Label
        LigneAPanel                     matlab.ui.container.Panel
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        maxEditField                    matlab.ui.control.NumericEditField
        maxLabel                        matlab.ui.control.Label
        LPBP2nmEditFieldLabel           matlab.ui.control.Label
        calibLP2                        matlab.ui.control.NumericEditField
        SPBP1nmEditFieldLabel           matlab.ui.control.Label
        calibSP1                        matlab.ui.control.NumericEditField
        WidthAnmEditFieldLabel          matlab.ui.control.Label
        calibWidthA                     matlab.ui.control.NumericEditField
        calibPeakA                      matlab.ui.control.NumericEditField
        PeakAnmEditFieldLabel           matlab.ui.control.Label
        RotationdesfiltresPanel         matlab.ui.container.Panel
        calibBP1Button                  matlab.ui.control.StateButton
        calibCP1Spinner                 matlab.ui.control.Spinner
        CP1SpinnerLabel                 matlab.ui.control.Label
        calibBP2Spinner                 matlab.ui.control.Spinner
        BP2Label                        matlab.ui.control.Label
        calibCP1Button                  matlab.ui.control.StateButton
        calibBP2Button                  matlab.ui.control.StateButton
        calibBP1Spinner                 matlab.ui.control.Spinner
        BP1SpinnerLabel                 matlab.ui.control.Label
        CalibrationdesFiltresPassesbandePanel  matlab.ui.container.Panel
        CalibrationBP4Button            matlab.ui.control.Button
        CalibrationBP3Button            matlab.ui.control.Button
        CalibrationBP2Button            matlab.ui.control.Button
        CalibrationBP1Button            matlab.ui.control.Button
        LigneAPanel_3                   matlab.ui.container.Panel
        LigneBPanel_4                   matlab.ui.container.Panel
        ParamtredecalibrationPanel      matlab.ui.container.Panel
        SeuilEditField                  matlab.ui.control.NumericEditField
        SeuilEditFieldLabel             matlab.ui.control.Label
        AngledefinEditField             matlab.ui.control.NumericEditField
        AngledefinLabel                 matlab.ui.control.Label
        StepEditField                   matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        AnglededpartEditField           matlab.ui.control.NumericEditField
        anglededpartLabel               matlab.ui.control.Label
        AXcalibA                        matlab.ui.control.UIAxes
        AXcalib                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Instr
        comA ='COM8'
        comB='COM6'
        mirA = elliptec_driver([1,2,3],'COM8')
        mirB = elliptec_driver([1,2,3],'COM6')
        p1=0
        p2=0
        p3=0
        p4=0
        p5=0
        p6=0
        CP1=-2
        CP2=-2
        balayageEnAngle = 0:6:360; % Valeur par défaut
        seuil = 0.25
        debut=0;
        fin = 360;
        step = 6;
        ybackground = 1:2000 * 0 ; % default 0 use set background to modify
        yLED = 1:2000 * 0 ; % default 0 use set background to modify
       
        BP1 = struct('x', 1:2000, 'y', 1:2000, 'DATA', ones(10,10), 'SIG', ones(10,10), ...
                'X', ones(2,10), 'angle', 1:20, ...
                'x0LP', 553.2695, 'neffLP', 1.8244, 'phaseLP', 38.9628, ...
                'x0SP', 575.0385, 'neffSP', 1.8384, 'phaseSP', 38.9620, 'calibration', 38.9624, ...
                'calibrationLP', 38.9628,'calibrationSP', 38.9620);
        BP2 = struct('x', 1:2000, 'y', 1:2000, 'DATA', ones(10,10), 'SIG', ones(10,10), ...
                'X', ones(2,10), 'angle', 1:20, ...
                'x0LP', 553.4564, 'neffLP', 1.8260, 'phaseLP', 35.7945, ...
                'x0SP', 575.3908, 'neffSP', 1.8389, 'phaseSP', 38.4003, 'calibration', 35.7890, ...
                'calibrationLP',35.7835,'calibrationSP', 35.7945);
        BP3 = struct('x', 1:2000, 'y', 1:2000, 'DATA', ones(10,10), 'SIG', ones(10,10), ...
                'X', ones(2,10), 'angle', 1:20, ...
                'x0LP', 553.2695, 'neffLP', 1.8244, 'phaseLP', 38.9628, ...
                'x0SP', 575.0385, 'neffSP', 1.8384, 'phaseSP', 38.9620, 'calibration', 38.9624, ...
                'calibrationLP', 38.9628,'calibrationSP', 38.9620);
        BP4 = struct('x', 1:2000, 'y', 1:2000, 'DATA', ones(10,10), 'SIG', ones(10,10), ...
                'X', ones(2,10), 'angle', 1:20, ...
                'x0LP', 553.4564, 'neffLP', 1.8260, 'phaseLP', 35.7945, ...
                'x0SP', 575.3908, 'neffSP', 1.8389, 'phaseSP', 38.4003, 'calibration', 35.7890, ...
                'calibrationLP',35.7835,'calibrationSP', 35.7945);
    end


    methods (Access = public)

        function start(app)
            imshow('loading.png', 'Parent', app.AXcalib)
            title(app.AXcalib,'Chargement du spectro')
            pause(2)
            app.Instr = SpectroInit(app);
            cla(app.AXcalib)
            imshow('arrow.png', 'Parent', app.AXcalib)
            title(app.AXcalib,'Chargement terminé')
        end

        function [x,y] = mesure(app)
            MACHINE = app.Instr;
            spectro = app.Instr.InstrInfo.Spectrometer;
            MACHINE.AndorReadoutModeDropDown.Value = "Single-track";

            [ret]=SetShutter(1, 0, 0, 0);%   Open Shutter IN FULLY AUTO
            CheckWarning(ret);

            % set single track ROI
            [ret]=SetSingleTrack(72,50);%)app.centralvalueSpinner.Value, app.heightSpinner.Value);
            CheckWarning(ret);
            [ret,xpixels, ~] = GetDetector;           %   Get the CCD size
            CheckWarning(ret);
            MACHINE.InstrInfo.Spectrometer.xpixels= xpixels;

            acqcode = 1; %Single scan for background if requested
            trigmode=0;
            readcode = 3;

            MACHINE.AcqTimeEditField.Value = 1;

            [ret]=SetAcquisitionMode(acqcode);                  %   Set acquisition mode; 1 for Single Scan
            CheckWarning(ret);
            [ret]=SetExposureTime(double(MACHINE.AcqTimeEditField.Value));                  %   Set exposure time in second
            CheckWarning(ret);
            [ret]=SetReadMode(readcode);                         %   Set read mode; 4 for Image
            CheckWarning(ret);
            [ret]=SetTriggerMode(trigmode);                      %   Set internal trigger mode
            CheckWarning(ret);

            MACHINE.InstrInfo.CCD.bkg =0 ;% acquire_background(app,0);

            MACHINE.AndorAcqModeDropDown.Value ="Single scan";
            MACHINE.thismeas = 'Spectrum';
            [ret] = StartAcquisition();
            CheckWarning(ret);

            [ret] = WaitForAcquisition();
            CheckWarning(ret);


            [ret, imageData] = GetMostRecentImage(xpixels);
            CheckWarning(ret);
            x = double(spectro.calib);
            y = double(imageData-MACHINE.InstrInfo.CCD.bkg);
            [ret]=AbortAcquisition;
            CheckWarning(ret);

        end

        function change_balayageEnAngle(app, val,change)
            if change == 1
                app.debut = val;
            elseif change == 3
                app.fin = val;
            elseif change == 2
                app.step = val;
            else
                error('something wrong append check varibales')
            end
            disp([app.fin,app.debut,app.step])
            app.balayageEnAngle = app.debut : app.step : app.fin;
            disp(['Nouvelle valeur de balayage en angle: ', num2str(app.balayageEnAngle)]);
        end
        function change_seuil(app, newseuil) 
            if (newseuil)<=1 && (newseuil>=0)
                app.seuil = newseuil;
                disp(['Nouveau seuil: ', num2str(app.seuil)]);
            else
                error('wrong threshold')
            end
        end

        function Instr = SpectroInit(app) % Initialise connection
            [ret] = ATSpectrographInitialize('');
            ATSpectrographCheckError(ret);

            % Initialise connection with Andor CCD
            ret = AndorInitialize('');
            [ret, Instr.InstrInfo.CCD.Capabilities] = GetCapabilities();
            CheckWarning(ret);

            % Check if CCD is cooled and turn on cooling
            [ret, Instr.InstrInfo.CCD.isCoolerOn] = IsCoolerOn();
            Instr.InstrInfo.CCD.SetT = -80;
            [ret] = SetTemperature(Instr.InstrInfo.CCD.SetT);

            if ~Instr.InstrInfo.CCD.isCoolerOn
                [ret] = CoolerON(); % Turn on temperature cooler
            end

            [ret, Instr.InstrInfo.CCD.CoolingOn] = IsCoolerOn();
            [ret, Instr.InstrInfo.CCD.Temperature] = GetTemperature();

            if Instr.InstrInfo.CCD.CoolingOn ~= 1 % au cas ou
                fprintf('CCD.CoolingOn~=1\n');
            end

            [ret, Instr.InstrInfo.CCD.PixelSize] = GetPixelSize();
            [ret, Instr.InstrInfo.CCD.xpixels, Instr.InstrInfo.CCD.ypixels] = GetDetector();

            % Check num devices = 1
            [ret, Instr.InstrInfo.Spectrometer.deviceCount] = ATSpectrographGetNumberDevices();
            ATSpectrographCheckWarning(ret);

            if Instr.InstrInfo.Spectrometer.deviceCount ~= 1 && Instr.InstrInfo.Spectrometer.deviceCount ~= 0
                error('more than 1 spectrometer found');
            else
                i = 0;
            end

            % Get serial number to check connection
            [ret, Instr.InstrInfo.Spectrometer.serial] = ATSpectrographGetSerialNumber(0, 256);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.noGratings] = ATSpectrographGetNumberGratings(0);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.currGrating] = ATSpectrographGetGrating(0);
            ATSpectrographCheckWarning(ret);

            [ret] = ATSpectrographSetGrating(0, 2); % modifier le gratting
            ATSpectrographCheckWarning(ret);

            [ret] = ATSpectrographSetWavelength(0, 550); % centre de la mesure
            ATSpectrographCheckWarning(ret);

            switch Instr.InstrInfo.Spectrometer.currGrating
                case 1
                    Instr.GratingDropDown.Value = '150';
                case 2
                    Instr.GratingDropDown.Value = '600';
                case 3
                    Instr.GratingDropDown.Value = '1200';
            end

            for grat = 1:3
                [ret, Instr.InstrInfo.Spectrometer.currGratingInfo(grat).lines, Instr.InstrInfo.Spectrometer.currGratingInfo(grat).blaze, Instr.InstrInfo.Spectrometer.currGratingInfo(grat).home, Instr.InstrInfo.Spectrometer.currGratingInfo(grat).offset] = ATSpectrographGetGratingInfo(0, grat, 256);
                ATSpectrographCheckWarning(ret);
            end

            [ret, Instr.InstrInfo.Spectrometer.currWL] = ATSpectrographGetWavelength(0);
            ATSpectrographCheckWarning(ret);

            Instr.WavelengthnmEditField.Value = Instr.InstrInfo.Spectrometer.currWL;

            [ret, Instr.InstrInfo.Spectrometer.WLmin_bef, Instr.InstrInfo.Spectrometer.WLmax_bef] = ATSpectrographGetWavelengthLimits(0, Instr.InstrInfo.Spectrometer.currGrating);
            ATSpectrographCheckWarning(ret);

            [ret] = ATSpectrographSetPixelWidth(0, Instr.InstrInfo.CCD.PixelSize);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.isshutter] = ATSpectrographShutterIsPresent(0);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.isflipperinput] = ATSpectrographFlipperMirrorIsPresent(0, 1);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.isflipperoutput] = ATSpectrographFlipperMirrorIsPresent(0, 2);
            ATSpectrographCheckWarning(ret);

            [ret] = ATSpectrographSetNumberPixels(0, Instr.InstrInfo.CCD.xpixels);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.calib] = ATSpectrographGetCalibration(0, Instr.InstrInfo.CCD.xpixels);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.WLmin, Instr.InstrInfo.Spectrometer.WLmax] = ATSpectrographGetWavelengthLimits(0, Instr.InstrInfo.Spectrometer.currGrating);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.low, Instr.InstrInfo.Spectrometer.high] = ATSpectrographGetCCDLimits(0, 1);
            ATSpectrographCheckWarning(ret);

            [ret, Instr.InstrInfo.Spectrometer.port] = ATSpectrographGetFlipperMirror(0, 1);
            ATSpectrographCheckError(ret);

            switch Instr.InstrInfo.Spectrometer.port
                case 0
                    Instr.InputportDropDown.Value = 'Direct';
                case 1
                    Instr.InputportDropDown.Value = 'Side';
            end

            [ret] = ATSpectrographSetFlipperMirror(0, 1, 1); % 'Direct' =0 ;'Side' = 1
            ATSpectrographCheckError(ret);

            [ret, Instr.InstrInfo.Spectrometer.port] = ATSpectrographGetFlipperMirror(0, 2);
            ATSpectrographCheckError(ret);

            switch Instr.InstrInfo.Spectrometer.port
                case 0
                    Instr.OutputportDropDown.Value = 'CCD';
                case 1
                    Instr.OutputportDropDown.Value = 'Free space';
            end

            for i = 0:2
                [ret, shutterbncpossible] = ATSpectrographIsShutterModePossible(0, i);
                Instr.InstrInfo.Spectrometer.shutterbncpossible(i + 1) = shutterbncpossible;
            end

            [ret] = ATSpectrographSetShutter(0, 2);
            Instr.InstrInfo.Spectrometer.shuttermode(2) = 2;

            [ret, shuttermode] = ATSpectrographGetShutter(0);
            Instr.InstrInfo.Spectrometer.shuttermode(1) = shuttermode;

            Instr.InstrInfo.Spectrometer = Instr.InstrInfo.Spectrometer;
        end
        function updateSpectrum(app,ax, peak1, Width1)
            
            cla(ax);

            if isempty(peak1), peak1 = 0; end
            if isempty(Width1), Width1 = 1; end

            x2LP = app.BP2.x0LP;
            x0SP = app.BP1.x0SP;
            neffSP = app.BP1.neffSP;
            neff2LP = app.BP2.neffLP;
            neffLP = app.BP1.neffLP;
            neff2SP = app.BP2.neffSP;

            [theta1, theta2, theta3] = Anatole_Calcule_des_Angles(peak1, Width1 / 2, x2LP, x0SP, neffSP, neff2LP, neffLP, neff2SP);

            app.p4=set_position(app.mirA,1,-90 + app.BP1.calibration);
            app.p5=set_position(app.mirA,2,-90 + app.BP2.calibration);
            app.p6=set_position(app.mirA,3,-90 + app.CP1);
            [~,app.yLED] = mesure(app);
            app.p1=set_position(app.mirA,1,theta1 + app.BP1.calibration);
            [x,y] = mesure(app);

            y1 = (y-app.ybackground)./(app.yLED-app.ybackground);
            plot(ax, x, y1, 'DisplayName', 'BP1','Color',[0,0,1,0.7],'LineStyle','--')
            title(ax, 'Spectre');
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            hold(ax, 'on');
            app.p4=set_position(app.mirA,1,-90 + app.BP1.calibration);
            app.p5=set_position(app.mirA,2,theta2 + app.BP2.calibration);
            [x,y] = mesure(app);
            y2 = (y-app.ybackground)./(app.yLED-app.ybackground);
            plot(ax, x, y2, 'DisplayName', 'BP2','Color',[1,0,0,0.7],'LineStyle','--')

            app.p4=set_position(app.mirA,1,theta1 + app.BP1.calibration);
            app.p5=set_position(app.mirA,2,theta2 + app.BP2.calibration);
            app.p6=set_position(app.mirA,3,theta3 + app.CP1);
            [x, y] = mesure(app);
            y = (y-app.ybackground)./(app.yLED-app.ybackground);
            area(ax, x, y, 'DisplayName', 'FT')
            legend(ax)
            plot(ax, [peak1, peak1], [0, max(y)],'DisplayName', 'peak expected')
            plot(ax, [peak1 + Width1 / 2, peak1 + Width1 / 2], [0, max(y)], 'g', 'DisplayName', 'SP expected')
            plot(ax, [peak1 - Width1 / 2, peak1 - Width1 / 2], [0, max(y)], 'g', 'DisplayName', 'LP expected')
            hold(ax, 'off');

        end
        
        function updateSpectrum2(app,ax, peak2, Width2)

            cla(ax);

            if isempty(peak2), peak2 = 0; end
            if isempty(Width2), Width2 = 1; end

            x2LP = app.BP4.x0LP;
            x0SP = app.BP3.x0SP;
            neffSP = app.BP3.neffSP;
            neff2LP = app.BP4.neffLP;
            neffLP = app.BP3.neffLP;
            neff2SP = app.BP4.neffSP;

            [theta1, theta2, theta3] = Anatole_Calcule_des_Angles(peak2, Width2 / 2, x2LP, x0SP, neffSP, neff2LP, neffLP, neff2SP);

            app.p1=set_position(app.mirB,1,-90 + app.BP1.calibration);
            app.p2=set_position(app.mirB,2,-90 + app.BP2.calibration);
            app.p3=set_position(app.mirB,3,-90 + app.CP1);
            [~,yl] = mesure(app);
            app.p1=set_position(app.mirB,1,theta1 + app.BP1.calibration);
            [x,y] = mesure(app);

            y1 = (y - min(y)) ./ (yl - min(y));
            plot(ax, x, y1, 'DisplayName', 'BP1','Color',[0,0,1,0.2],'LineStyle',':')
            title(ax, 'Spectre');
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            hold(ax, 'on');
            app.p1=set_position(app.mirB,1,-90 + app.BP1.calibration);
            app.p2=set_position(app.mirB,2,theta2 + app.BP2.calibration);
            [x,y] = mesure(app);
            y2 = (y - min(y)) ./ (yl - min(y));
            plot(ax, x, y2, 'DisplayName', 'BP2','Color',[1,0,0,0.2],'LineStyle',':')
            app.p2=set_position(app.mirB,2,-90 + app.BP2.calibration);
            app.p3=set_position(app.mirB,3,theta3 + app.CP1);
            [x,y] = mesure(app);
            y3 = (y - min(y)) ./ (yl - min(y));
            plot(ax, x, y3, 'DisplayName', 'CP','Color',[1,0,1,0.2],'LineStyle',':')
            plot(ax, x, y1 .* y2 .* y3, 'DisplayName', 'product y1*y2*y3','Color',[0,1,1,0.2],'LineStyle',':')
            ylim(ax,[-0.1,1.1])
            app.p1=set_position(app.mirB,1,theta1 + app.BP1.calibration);
            app.p2=set_position(app.mirB,2,theta2 + app.BP2.calibration);
            app.p3=set_position(app.mirB,3,theta3 + app.CP1);
            [x, y] = mesure(app);
            y = (y - min(y)) ./ (yl - min(y));
            plot(ax, x, y, 'DisplayName', 'FT')
            legend(ax)
            plot(ax, [peak2, peak2], [0, max(y)],'DisplayName', 'peak expected')
            plot(ax, [peak2 + Width2 / 2, peak2 + Width2 / 2], [0, max(y)], 'g', 'DisplayName', 'SP expected')
            plot(ax, [peak2 - Width2 / 2, peak2 - Width2 / 2], [0, max(y)], 'g', 'DisplayName', 'LP expected')
            hold(ax, 'off')
        end

        function moovesystemA(app, peak1, Width1)

            if isempty(peak1), peak1 = 0; end
            if isempty(Width1), Width1 = 1; end

            x2LP = app.BP2.x0LP;
            x0SP = app.BP1.x0SP;
            neffSP = app.BP1.neffSP;
            neff2LP = app.BP2.neffLP;
            neffLP = app.BP1.neffLP;
            neff2SP = app.BP2.neffSP;

            [theta1, theta2, theta3] = Anatole_Calcule_des_Angles(peak1, Width1 / 2, x2LP, x0SP, neffSP, neff2LP, neffLP, neff2SP);  

            app.p1=set_position(app.mirA,1,app.BP1.calibration+theta1);
            app.p2=set_position(app.mirA,2,app.BP2.calibration+theta2);
            app.p3=set_position(app.mirA,3,app.CP1+theta3);
        end

        function moovesystemB(app, peak2, Width2)

            if isempty(peak2), peak2 = 0; end
            if isempty(Width2), Width2 = 1; end

            x2LP = app.BP4.x0LP;
            x0SP = app.BP3.x0SP;
            neffSP = app.BP3.neffSP;
            neff2LP = app.BP4.neffLP;
            neffLP = app.BP3.neffLP;
            neff2SP = app.BP4.neffSP;

            [theta1, theta2, theta3] = Anatole_Calcule_des_Angles(peak2, Width2 / 2, x2LP, x0SP, neffSP, neff2LP, neffLP, neff2SP);
 
            app.p4=set_position(app.mirB,1,app.BP3.calibration+theta1);
            app.p5=set_position(app.mirB,2,app.BP4.calibration+theta2);
            app.p6=set_position(app.mirB,3,app.CP2+theta3);
        end

        function calibrationData = calibrationA(app, range, BP_nbr, seuil, axSpectrum)
            % Exécution du script de calibration
            cla(app.AXcalib,'reset')
            xlabel(app.AXcalib, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(app.AXcalib, 'Intensity', 'Interpreter', 'latex')
            title('calibration sur ligne A')
            
            calibration2temp = -140; % Définir la valeur de calibration2 temporaire

            app.p1=set_position(app.mirA,1,calibration2temp+90);
            app.p2=set_position(app.mirA,2,calibration2temp+90);
            app.p3=set_position(app.mirA,3,-90 + app.CP1);
            [x,yl] = mesure(app);
            DATA = zeros(length(range), 2000); % Matrice de données d'acquisition
            SIG = DATA; % Matrice des signaux filtrés
            Xtemp = zeros(length(range), 2); % Matrice temporaire des positions X
            angletemp = zeros(size(range)); % Vecteur temporaire des angles


            % Boucle sur la plage d'angles
            for j = 1:length(range)
                i = range(j); % Angle courant
                [~]=set_position(app.mirA,BP_nbr,i);
                [x,y] = mesure(app);
                y = (y-min(y))./(yl-min(y));
                DATA(j, :) = y; % Stocker les données dans DATA


                sig = normalize(y,'range');
                % Appliquer un filtre médian pour lisser et normaliser les données
                plot(axSpectrum,x,sig)
                hold(axSpectrum,'on')
                
                SIG(j, :) = sig; % Stocker le signal normalisé dans SIG

                sig = normalize(sig,'range');
                DERIV = sig > seuil;
                Xfirst = find(DERIV, 1, 'first');
                Xlast = find(DERIV, 1, 'last');
                scatter([x(Xfirst),x(Xlast)],[y(Xfirst),y(Xlast)])
                hold(axSpectrum,'off')
                disp([[x(Xfirst),x(Xlast)],[y(Xfirst),y(Xlast)]])
                if ~isempty(Xfirst) && ~isempty(Xlast) && (x(Xlast) - x(Xfirst)) > 17 && (x(Xlast) - x(Xfirst)) < 23 && x(Xlast) < 580 && x(Xfirst) > 480 && length(Xfirst) == 1 && length(Xlast) == 1 && sig(Xfirst) > (seuil - 0.15) && sig(Xfirst) < (seuil + 0.15) && sig(Xlast) > (seuil - 0.15) && sig(Xlast) < (seuil + 0.15)

                    % Stocker les positions valides dans Xtemp
                    Xtemp(j, :) = [x(Xfirst), x(Xlast)];
                    angletemp(j) = i; % Stocker l'angle correspondant
                    fprintf("ADDED\n");
                    % Marquer les positions détectées sur le graphique
                    xlabel(axSpectrum, '$\lambda$ nm', 'Interpreter', 'latex')
                    ylabel(axSpectrum, 'Intensity', 'Interpreter', 'latex')
                    plot(axSpectrum, x, sig,'DisplayName','BP transmitance')
                    hold(axSpectrum, 'on')
                    plot(axSpectrum, [min(x), max(x)], [seuil, seuil],'DisplayName','Seuil')
                    scatter(axSpectrum, [x(Xfirst), x(Xlast)], [SIG(j, Xfirst), SIG(j, Xlast)],'DisplayName','Loc SP & LP');
                    ylim(axSpectrum, [-0.1, 1.1])
                    hold(axSpectrum, 'off')
                end
            end
            hold(axSpectrum, 'off');

            % Filtrer les positions valides stockées dans Xtemp
            valid_idx = Xtemp(:, 1) ~= 0;
            X = Xtemp(valid_idx, :);
            angle = angletemp(valid_idx);

            % Ajuster les courbes pour obtenir les paramètres de calibration
            [x0LP, neffLP, phaseLP, x0SP, neffSP, phaseSP] = AnatoleCurveFit(angle, X(:, 1), max(X(:, 1)), angle(X(:, 1) == max(X(:, 1))), X(:, 2), max(X(:, 2)), angle(X(:, 2) == max(X(:, 2))));
            % Calculer la valeur de calibration
            calibration = (mod(phaseLP, 180) + mod(phaseSP, 180)) / 2;
            calibrationLP = mod(phaseLP, 180);
            calibrationSP = mod(phaseSP, 180);
            % Acquisition des données de spectroscopie pour la calibration
            [~]=set_position(app.mirB,BP_nbr,calibration);
            [x,y] = mesure(app);

            % Stocker toutes les variables dans la propriété calibrationData
            calibrationData = struct('x', x, 'y', y, 'DATA', DATA, 'SIG', SIG, ...
                'X', X, 'angle', angle, ...
                'x0LP', x0LP, 'neffLP', neffLP, 'phaseLP', phaseLP, ...
                'x0SP', x0SP, 'neffSP', neffSP, 'phaseSP', phaseSP, ...
                'calibration', calibration, 'calibrationLP', calibrationLP, ...
                'calibrationSP', calibrationSP);
        end

        function calibrationData = calibrationB(app, range, BP_nbr, seuil, axSpectrum)
            cla(app.AXcalib,'reset')
            xlabel(app.AXcalib, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(app.AXcalib, 'Intensity', 'Interpreter', 'latex')
            title('Calibration sur ligne B')
            % Exécution du script de calibration
            calibration2temp = -140; % Définir la valeur de calibration2 temporaire

            app.p4=set_position(app.mirB,1,calibration2temp+90);
            app.p5=set_position(app.mirB,2,calibration2temp+90);
            app.p6=set_position(app.mirB,3,-90 + app.CP1);
            [x,y] = mesure(app);
            DATA = zeros(length(range), 2000); % Matrice de données d'acquisition
            SIG = DATA; % Matrice des signaux filtrés
            Xtemp = zeros(length(range), 2); % Matrice temporaire des positions X
            angletemp = zeros(size(range)); % Vecteur temporaire des angles


            % Boucle sur la plage d'angles
            for j = 1:length(range)
                i = range(j); % Angle courant
                [~]=set_position(app.mirB,BP_nbr,i);
                [x,y] = mesure(app);
                y = (y-min(y))./(yl-min(y));
                DATA(j, :) = y; % Stocker les données dans DATA


                sig = normalize(y,'range');
                SIG(j, :) = sig; % Stocker le signal normalisé dans SIG
                % Détecter les points où le signal dépasse un seuil (0.22)
                DERIV = sig > seuil;
                Xfirst = find(DERIV, 1, 'first');
                Xlast = find(DERIV, 1, 'last');

                if ~isempty(Xfirst) && ~isempty(Xlast) && (x(Xlast) - x(Xfirst)) > 17 && (x(Xlast) - x(Xfirst)) < 23 && x(Xlast) < 580 && x(Xfirst) > 480 && length(Xfirst) == 1 && length(Xlast) == 1 && sig(Xfirst) > (seuil - 0.15) && sig(Xfirst) < (seuil + 0.15) && sig(Xlast) > (seuil - 0.15) && sig(Xlast) < (seuil + 0.15)

                    % Stocker les positions valides dans Xtemp
                    Xtemp(j, :) = [x(Xfirst), x(Xlast)];
                    angletemp(j) = i; % Stocker l'angle correspondant
                    fprintf("ADDED\n");
                    % Marquer les positions détectées sur le graphique
                    xlabel(axSpectrum, '$\lambda$ nm', 'Interpreter', 'latex')
                    ylabel(axSpectrum, 'Intensity', 'Interpreter', 'latex')
                    plot(axSpectrum, x, sig,'DisplayName','BP transmitance')
                    hold(axSpectrum, 'on')
                    plot(axSpectrum, [min(x), max(x)], [seuil, seuil],'DisplayName','Seuil')
                    scatter(axSpectrum, [x(Xfirst), x(Xlast)], [SIG(j, Xfirst), SIG(j, Xlast)],'DisplayName','Loc SP & LP');
                    ylim(axSpectrum, [-0.1, 1.1])
                    hold(axSpectrum, 'off')
                end
            end
            hold(axSpectrum, 'off');

            % Filtrer les positions valides stockées dans Xtemp
            valid_idx = Xtemp(:, 1) ~= 0;
            X = Xtemp(valid_idx, :);
            angle = angletemp(valid_idx);

            % Ajuster les courbes pour obtenir les paramètres de calibration
            [x0LP, neffLP, phaseLP, x0SP, neffSP, phaseSP] = AnatoleCurveFit(angle, X(:, 1), max(X(:, 1)), angle(X(:, 1) == max(X(:, 1))), X(:, 2), max(X(:, 2)), angle(X(:, 2) == max(X(:, 2))));
            % Calculer la valeur de calibration
            calibration = (mod(phaseLP, 180) + mod(phaseSP, 180)) / 2;
            calibrationLP = mod(phaseLP, 180);
            calibrationSP = mod(phaseSP, 180);
            % Acquisition des données de spectroscopie pour la calibration
            [~]=set_position(app.mirB,BP_nbr,calibration);

            % Stocker toutes les variables dans la propriété calibrationData
            calibrationData = struct('x', x, 'y', y, 'DATA', DATA, 'SIG', SIG, ...
                'X', X, 'angle', angle, ...
                'x0LP', x0LP, 'neffLP', neffLP, 'phaseLP', phaseLP, ...
                'x0SP', x0SP, 'neffSP', neffSP, 'phaseSP', phaseSP, ...
                'calibration', calibration, 'calibrationLP', calibrationLP, ...
                'calibrationSP', calibrationSP);
        end


        function runCalibration(app,channel, BP_nbr, Ax)
            cla(Ax);
            range = app.balayageEnAngle;
            if channel == 'A'
                disp('yey')
                data = calibrationA(app, range, BP_nbr, app.seuil, Ax);
            elseif channel=='B'
                data = calibrationB(app, range, BP_nbr, app.seuil, Ax);
            else
                error('channel ?')
            end
            if (BP_nbr == 1) && (channel == 'A') % Ajuster au besoin
                app.BP1 = data;
                disp('Calibration Data Stored:');
                disp(app.BP1);
            elseif (BP_nbr == 2) && (channel=='A')
                app.BP2 = data;
                disp('Calibration Data Stored:');
                disp(app.BP2);
            elseif (BP_nbr == 1) && (channel=='B')
                app.BP3 = data;
                disp('Calibration Data Stored:');
                disp(app.BP3);
            elseif (BP_nbr == 2) && (channel=='B')
                app.BP4 = data;
                disp('Calibration Data Stored:');
                disp(app.BP4);
            else
                error('wrong BP nbr or communication channel')
            end
        end
        
        function graph(app,ax,SP1,LP1,SP2,LP2)
            x = 480:0.25:580;
            y1 = (480:0.25:580).*0;
            y2 = (480:0.25:580).*0;
            y1(x>=LP1 & x<=SP1)=1;
            y2(x>=LP2 & x<=SP2)=1;
            plot(ax,x,y1,'Color','blue')
            hold(ax,'on')
            plot(ax,x,y2,'Color','red')
            area(ax,x,y1.*y2,'FaceColor',[0.4940 0.1840 0.5560],'FaceAlpha',0.25,'EdgeAlpha',0.1)
            ylim(ax,[-0.1,1.1])
            hold(ax,'off')
        end

        function updatecalibALP2(app,ax,LP2)
            if isempty(LP2), LP2 = 0; end

            x2LP = app.BP2.x0LP;
            neff2LP = app.BP2.neffLP;
            theta2 = -Anatole_Courbe_Inverse(x2LP, neff2LP, LP2);
            
            [x, y, app.p2] = Anatole_spectro_filter(theta2 + app.BP2.calibration, app.Instr.InstrInfo.Spectrometer, 2,app.comA);

            plot(ax,x,y)
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            title('New position BP2 ligne A')
        end
        function updatecalibASP1(app,ax,SP1)
            if isempty(SP1), SP1 = 0; end

            x0SP = app.BP1.x0SP;
            neffSP = app.BP1.neffSP;
            theta1 = Anatole_Courbe_Inverse(x0SP, neffSP, SP1);
            [x, y, app.p1] = Anatole_spectro_filter(app.BP1.calibration + theta1, app.Instr.InstrInfo.Spectrometer, 1,app.comA);

            plot(ax,x,y)
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            title('New position BP2 ligne A')
        end

        function updatecalibBLP2(app,ax, LP2)

            if isempty(LP2), LP2 = 0; end

            x2LP = app.BP4.x0LP;
            neff2LP = app.BP4.neffLP;
            theta2 = -Anatole_Courbe_Inverse(x2LP, neff2LP, LP2);

            [x, y, app.p5] = Anatole_spectro_filter(theta2 + app.BP4.calibration, app.Instr.InstrInfo.Spectrometer, 2,app.comB);
            plot(ax,x,y)
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            title('New position BP2 ligne A')
        end

        function updatecalibBSP1(app,ax, SP1)
            if isempty(SP1), SP1 = 0; end

            x0SP = app.BP3.x0SP;
            neffSP = app.BP3.neffSP;
            theta1 = Anatole_Courbe_Inverse(x0SP, neffSP, SP1);
            [x, y, app.p4] = Anatole_spectro_filter(app.BP3.calibration + theta1, app.Instr.InstrInfo.Spectrometer, 1,app.comB);
            plot(ax,x,y)
            xlabel(ax, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(ax, 'Intensity', 'Interpreter', 'latex')
            title('New position BP2 ligne A')
        end


        function updateALP2(app, LP2)

            if isempty(LP2), LP2 = 0; end

            x2LP = app.BP2.x0LP;
            neff2LP = app.BP2.neffLP;
            theta2 = -Anatole_Courbe_Inverse(x2LP, neff2LP, LP2);
            app.p2=set_position(app.mirA,2,theta2 + app.BP2.calibration);
        end
        function updateASP1(app, SP1)
            if isempty(SP1), SP1 = 0; end

            x0SP = app.BP1.x0SP;
            neffSP = app.BP1.neffSP;
            theta1 = Anatole_Courbe_Inverse(x0SP, neffSP, SP1);
            app.p1=set_position(app.mirA,1,theta1 + app.BP1.calibration);

        end
        function updateBLP2(app, LP2)

            if isempty(LP2), LP2 = 0; end

            x2LP = app.BP4.x0LP;
            neff2LP = app.BP4.neffLP;
            theta2 = -Anatole_Courbe_Inverse(x2LP, neff2LP, LP2);
            app.p5=set_position(app.mirB,2,theta2 + app.BP4.calibration);
        end
        function updateBSP1(app, SP1)
            if isempty(SP1), SP1 = 0; end

            x0SP = app.BP3.x0SP;
            neffSP = app.BP3.neffSP;
            theta1 = Anatole_Courbe_Inverse(x0SP, neffSP, SP1);
            app.p4=set_position(app.mirB,1,theta1 + app.BP3.calibration);

        end
        
        function updateBalayageParams(app,newval,change)
            disp(newval)
            disp(change)
            if ~isnan(newval) && ~isnan(change)
                app.change_balayageEnAngle(newval,change);
            else
                disp('Valeur invalide pour le balayage en angle.');
            end
        end

        function updateSeuil(app,newSeuil)
            if ~isnan(newSeuil)
                app.change_seuil(newSeuil);
            else
                disp('Valeur invalide pour le seuil.');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalibrationBP1Button
        function CalibrationBP1ButtonPushed(app, event)
            runCalibration(app,'A', 1, app.AXcalib);
        end

        % Button pushed function: CalibrationBP2Button
        function CalibrationBP2ButtonPushed(app, event)
            runCalibration(app,'A', 2, app.AXcalib);
        end

        % Button pushed function: CalibrationBP3Button
        function CalibrationBP3ButtonPushed(app, event)
            runCalibration(app,'B', 1, app.AXcalib);
        end

        % Button pushed function: CalibrationBP4Button
        function CalibrationBP4ButtonPushed(app, event)
            runCalibration(app,'B', 2, app.AXcalib);
        end

        % Value changed function: AnglededpartEditField
        function AnglededpartEditFieldValueChanged(app, event)
            updateBalayageParams(app,app.AnglededpartEditField.Value,1);
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            updateBalayageParams(app,app.StepEditField.Value,2);
        end

        % Value changed function: AngledefinEditField
        function AngledefinEditFieldValueChanged(app, event)
            updateBalayageParams(app,app.AngledefinEditField.Value,3);
        end

        % Value changed function: SeuilEditField
        function SeuilEditFieldValueChanged(app, event)
            updateSeuil(app,app.SeuilEditField.Value);
        end

        % Callback function
        function PeakAnmTextArea_2ValueChanged(app, event)
            updateSpectrum(app,app.AXcalibA, app.PeakAnmTextArea_2.Value, app.WidthAnmTextArea.Value);
        end

        % Callback function
        function WidthAnmTextAreaValueChanged(app, event)
            updateSpectrum(app,app.AXcalibA, app.PeakAnmTextArea_2.Value, app.WidthAnmTextArea.Value);
        end

        % Callback function
        function SPBP1nmTextAreaValueChanged(app, event)
            updateSP1(app,app.AXcalibA, app.SPBP1nmTextArea.Value);
        end

        % Callback function
        function LPBP2nmTextAreaValueChanged(app, event)
            updatecalibALP2(app,app.AXcalibA, app.LPBP2nmTextArea.Value);
        end

        % Value changed function: calibPeakA
        function calibPeakAValueChanged(app, event)
            updateSpectrum(app,app.AXcalibA, app.calibPeakA.Value, app.calibWidthA.Value);
            
        end

        % Value changed function: calibWidthA
        function calibWidthAValueChanged(app, event)
            updateSpectrum(app,app.AXcalibA, app.calibPeakA.Value, app.calibWidthA.Value);
            
        end

        % Value changed function: MS_PeakA
        function MS_PeakAValueChanged(app, event)
            width = app.MS_WidthA.Value;
            peak = app.MS_PeakA.Value;
            mini = app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2);
            if ((peak+width/2)-app.BP1.x0SP<=0) && ((peak-width/2)-mini>=0) 
                moovesystemA(app,app.MS_PeakA.Value,app.MS_WidthA.Value);
            elseif (width>=(app.BP1.x0SP-app.BP1.x0LP)) || (width>=(app.BP2.x0SP-app.BP2.x0LP)) || (peak)<=0 
                app.MS_WidthA.Value = -404;
                error('wrong value for width be sure filter asked is correct : too tall or too large')
            else
                app.MS_WidthA.Value = -404;
                error('wrong value for width be sure filter asked is correct : out of bound')
            end
            app.MS_BP1Spinner.Value = app.p1-app.BP1.calibration;
            app.MS_BP2Spinner.Value = app.p2-app.BP2.calibration;
            app.MS_CP1Spinner.Value = app.p3-app.CP1;
            app.MS_MaxSP1.Value = num2str(round(app.BP1.x0SP,2));
            app.MS_MinSP1.Value = num2str(round(app.BP1.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP1.neffSP).^2),2));

            app.MS_MaxLP2.Value = num2str(round(app.BP2.x0LP,2));
            app.MS_MinLP2.Value = num2str(round(app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2),2));

            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);
            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: MS_WidthA
        function MS_WidthAValueChanged(app, event)
            width = app.MS_WidthA.Value;
            peak = app.MS_PeakA.Value;
            mini = app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2);
            if ((peak+width/2)-app.BP1.x0SP<=0) && ((peak-width/2)-mini>=0) 
                moovesystemA(app,app.MS_PeakA.Value,app.MS_WidthA.Value);
            elseif (width>=(app.BP1.x0SP-app.BP1.x0LP)) || (width>=(app.BP2.x0SP-app.BP2.x0LP)) || (width)<=0 
                app.MS_WidthA.Value = -404;
                error('wrong value for width be sure filter asked is correct : too tall or too large')
            else
                app.MS_WidthA.Value = -404;
                error('wrong value for width be sure filter asked is correct : out of bound')
            end
            app.MS_SP1.Value = peak+width/2;
            app.MS_LP2.Value = peak-width/2;
            app.MS_BP1Spinner.Value = app.p1-app.BP1.calibration;
            app.MS_BP2Spinner.Value = app.p2-app.BP2.calibration;
            app.MS_CP1Spinner.Value = app.p3-app.CP1;
            app.MS_MaxSP1.Value = num2str(round(app.BP1.x0SP,2));
            app.MS_MinSP1.Value = num2str(round(app.BP1.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP1.neffSP).^2),2));

            app.MS_MaxLP2.Value = num2str(round(app.BP2.x0LP,2));
            app.MS_MinLP2.Value = num2str(round(app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2),2));

            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);
            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: MS_SP1
        function MS_SP1ValueChanged(app, event)
            updateASP1(app,app.MS_SP1.Value);
            app.MS_BP1Spinner.Value = app.p1-app.BP1.calibration;
            app.MS_CP1Spinner.Value = app.p3-app.CP1;
            app.MS_MaxSP1.Value = num2str(round(app.BP1.x0SP,2));
            app.MS_MinSP1.Value = num2str(round(app.BP1.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP1.neffSP).^2),2));

            app.MS_MaxLP2.Value = num2str(round(app.BP2.x0LP,2));
            app.MS_MinLP2.Value = num2str(round(app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2),2));

            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);
            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: MS_LP2
        function MS_LP2ValueChanged(app, event)
            updateALP2(app,app.MS_LP2.Value);
            app.MS_BP2Spinner.Value = app.p2-app.BP2.calibration;
            app.MS_MaxSP1.Value = num2str(round(app.BP1.x0SP,2));
            app.MS_MinSP1.Value = num2str(round(app.BP1.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP1.neffSP).^2),2));
            app.MS_MaxLP2.Value = num2str(round(app.BP2.x0LP,2));
            app.MS_MinLP2.Value = num2str(round(app.BP2.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP2.neffLP).^2),2));

            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);
            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: calibSP1
        function calibSP1ValueChanged(app, event)
            updatecalibSP1(app,app.AXcalibA,app.calibSP1.Value);
        end

        % Value changed function: calibLP2
        function calibLP2ValueChanged(app, event)
            updateLP2(app,app.AXcalibA,app.calibLP2.Value);
        end

        % Value changed function: calibBP1Button
        function calibBP1ButtonValueChanged(app, event)
            app.MS_BP1Spinner.Value = -90;
            [x,y,app.p1]  = Anatole_spectro_mirrror(-90+ app.BP1.calibration,app.mirA,1);
            plot(app.AXcalib,x,y)
            xlabel(app.AXcalibA, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(app.AXcalibA, 'Intensity', 'Interpreter', 'latex')
            title(app.AXcalibA,'ligne A : BP1 -90°')

        end

        % Value changed function: calibBP2Button
        function calibBP2ButtonValueChanged(app, event)
            app.p2=set_position(app.mirA,2,app.BP2.calibration-90);
        end

        % Value changed function: calibCP1Button
        function calibCP1ButtonValueChanged(app, event)
            app.p3=set_position(app.mirA,3,app.CP1-90);
        end

        % Value changed function: calibBP3Button
        function calibBP3ButtonValueChanged(app, event)
            app.p4=set_position(app.mirB,1,app.BP3.calibration-90);
        end

        % Value changed function: calibBP4Button
        function calibBP4ButtonValueChanged(app, event)
            app.p5=set_position(app.mirB,2,app.BP4.calibration-90);
        end

        % Value changed function: calibCP2Button
        function calibCP2ButtonValueChanged(app, event)
            app.p6=set_position(app.mirB,3,app.CP2-90);
        end

        % Value changed function: calibPeakB
        function calibPeakBValueChanged(app, event)
            updateSpectrum2(app,app.AXcalibA, app.calibPeakB.Value, app.calibWidthB.Value);
        end

        % Value changed function: calibWidthB
        function calibWidthBValueChanged(app, event)
            updateSpectrum2(app,app.AXcalibA, app.calibPeakB.Value, app.calibWidthB.Value); 
        end

        % Value changed function: calibSP3
        function calibSP3ValueChanged(app, event)
            updatecalibBSP1(app,app.AXcalibA,app.calibSP3.Value);
        end

        % Value changed function: calibLP4
        function calibLP4ValueChanged(app, event)
            updatecalibBLP2(app,app.AXcalibA,app.calibLP4.Value)
        end

        % Value changed function: MS_PeakB
        function MS_PeakBValueChanged(app, event)
            width = app.MS_WidthB.Value;
            peak = app.MS_PeakB.Value;
            mini = app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2);
            if ((peak+width/2)-app.BP3.x0SP<=0) && ((peak-width/2)-mini>=0) 
                moovesystemB(app,app.MS_PeakB.Value,app.MS_WidthB.Value);
                app.MS_SP3.Value = peak + width/2;
                app.MS_LP4.Value = peak-width/2;
            elseif (width>=(app.BP3.x0SP-app.BP3.x0LP)) || (width>=(app.BP4.x0SP-app.BP4.x0LP)) || (peak)<=0 
                app.MS_WidthB.Value = -404;
                error('wrong value for width be sure filter asked is correct : too tall or too large')
            else
                app.MS_WidthB.Value = -404;
                error('wrong value for width be sure filter asked is correct : out of bound')
            end
            app.MS_BP3Spinner.Value = app.p4-app.BP3.calibration;
            app.MS_BP4Spinner.Value = app.p5-app.BP4.calibration;
            app.MS_CP2Spinner.Value = app.p6-app.CP2;
            app.MS_MaxSP3.Value = num2str(round(app.BP3.x0SP,2));
            app.MS_MinSP3.Value = num2str(round(app.BP3.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP3.neffSP).^2),2));

            app.MS_MaxLP4.Value = num2str(round(app.BP4.x0LP,2));
            app.MS_MinLP4.Value = num2str(round(app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2),2));

            angle1 = app.MS_BP3Spinner.Value;
            angle2 = app.MS_BP4Spinner.Value;
            SP3 = app.BP3.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffSP)^2);
            LP3 = app.BP3.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffLP)^2);
            SP4 = app.BP4.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffSP)^2);
            LP4 = app.BP4.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffLP)^2);
            graph(app,app.AXgate2,SP3,LP3,SP4,LP4)
            
        end

        % Value changed function: MS_WidthB
        function MS_WidthBValueChanged(app, event)
            width = app.MS_WidthB.Value;
            peak = app.MS_PeakB.Value;
            mini = app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2);
            if ((peak+width/2)-app.BP3.x0SP<=0) && ((peak-width/2)-mini>=0) 
                moovesystemB(app,app.MS_PeakB.Value,app.MS_WidthB.Value)
                app.MS_SP3.Value = peak + width/2;
                app.MS_LP4.Value = peak-width/2;
            elseif (width>=(app.BP3.x0SP-app.BP3.x0LP)) || (width>=(app.BP4.x0SP-app.BP4.x0LP)) || (peak)<=0 
                app.MS_WidthB.Value = -404;
                error('wrong value for width be sure filter asked is correct : too tall or too large')
            else
                app.MS_WidthB.Value = -404;
                error('wrong value for width be sure filter asked is correct : out of bound')
            end

            app.MS_BP3Spinner.Value = app.p4-app.BP3.calibration;
            app.MS_BP4Spinner.Value = app.p5-app.BP4.calibration;
            app.MS_CP2Spinner.Value = app.p6-app.CP2;
            app.MS_MaxSP3.Value = num2str(round(app.BP3.x0SP,2));
            app.MS_MinSP3.Value = num2str(round(app.BP3.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP3.neffSP).^2),2));

            app.MS_MaxLP4.Value = num2str(round(app.BP4.x0LP,2));
            app.MS_MinLP4.Value = num2str(round(app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2),2));


            angle1 = app.MS_BP3Spinner.Value;
            angle2 = app.MS_BP4Spinner.Value;
            SP3 = app.BP3.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffSP)^2);
            LP3 = app.BP3.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffLP)^2);
            SP4 = app.BP4.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffSP)^2);
            LP4 = app.BP4.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffLP)^2);
            graph(app,app.AXgate2,SP3,LP3,SP4,LP4)

        end

        % Callback function
        function MS_SP3ValueChanged(app, event)
            updateBSP1(app,app.MS_SP3.Value)
        end

        % Callback function
        function MS_LP4ValueChanged(app, event)
            updateBLP2(app,app.MS_LP4.Value)
        end

        % Value changed function: MS_BP1Button
        function MS_BP1ButtonValueChanged(app, event)
            app.p1=set_position(app.mirA,1,-90+ app.BP1.calibration);
            app.MS_BP1Spinner.Value = -90;
            angle2 = app.MS_BP2Spinner.Value;
            
            if angle2 ~=-90
                SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP1.neffSP)^2);
                LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP1.neffLP)^2);
                x = 480:0.25:580;
                y1 = (480:0.25:580).*0;
                y1(x>=LP1 & x<=SP1)=1;
 
                plot(app.AXgate,x,y1,'Color','blue')
                hold(app.AXgate,'on')
                area(app.AXgate,x,y1,'FaceColor',[0.4940 0.1840 0.5560],'FaceAlpha',0.25,'EdgeAlpha',0.1)
                ylim(app.AXgate,[-0.1,1.1])
                hold(app.AXgate,'off')
            else
                cla(app.AXgate)
            end
        end

        % Value changed function: MS_BP2Button
        function MS_BP2ButtonValueChanged(app, event)
            app.p2=set_position(app.mirA,2,-90+ app.BP2.calibration);
            app.MS_BP2Spinner.Value = -90;
            angle = app.MS_BP1Spinner.Value;
            
            if angle ~=-90
                SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle)) / app.BP2.neffSP)^2);
                LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle)) / app.BP2.neffLP)^2);
                x = 480:0.25:580;
                y1 = (480:0.25:580).*0;
                y1(x>=LP2 & x<=SP2)=1;
 
                plot(app.AXgate,x,y1,'Color','blue')
                hold(app.AXgate,'on')
                area(app.AXgate,x,y1,'FaceColor',[0.4940 0.1840 0.5560],'FaceAlpha',0.25,'EdgeAlpha',0.1)
                ylim(app.AXgate,[-0.1,1.1])
                hold(app.AXgate,'off')
            else
                cla(app.AXgate)
            end
        end

        % Value changed function: MS_CP1Button
        function MS_CP1ButtonValueChanged(app, event)
            app.p3=set_position(app.mirA,3,-90 + app.CP1);
            app.MS_CP1Spinner.Value = -90;
        end

        % Value changed function: MS_BP3Button
        function MS_BP3ButtonValueChanged(app, event)
            app.p4=set_position(app.mirB,1,-90 + app.BP3.calibration);
            app.MS_BP3Spinner.Value=-90;
            angle = app.MS_BP4Spinner.Value;
            if angle ~=-90
                SP4 = app.BP4.x0SP * sqrt(1 - (sin(deg2rad(angle)) / app.BP4.neffSP)^2);
                LP4 = app.BP4.x0LP * sqrt(1 - (sin(deg2rad(angle)) / app.BP4.neffLP)^2);
                x = 480:0.25:580;
                y1 = (480:0.25:580).*0;
                y1(x>=LP4 & x<=SP4)=1;
 
                plot(app.AXgate2,x,y1,'Color','blue')
                hold(app.AXgate2,'on')
                area(app.AXgate2,x,y1,'FaceColor',[0.4940 0.1840 0.5560],'FaceAlpha',0.25,'EdgeAlpha',0.1)
                ylim(app.AXgate2,[-0.1,1.1])
                hold(app.AXgate2,'off')
            else
                cla(app.AXgate2)
            end
        end

        % Value changed function: MS_BP4Button
        function MS_BP4ButtonValueChanged(app, event)
            app.p5=set_position(app.mirB,2,-90 + app.BP3.calibration);
            app.MS_BP4Spinner.Value=-90;
            angle1 = app.MS_BP3Spinner.Value;
            
            if angle1 ~=-90
                SP3 = app.BP3.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffSP)^2);
                LP3 = app.BP3.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffLP)^2);
                x = 480:0.25:580;
                y1 = (480:0.25:580).*0;
                y1(x>=LP3 & x<=SP3)=1;
 
                plot(app.AXgate2,x,y1,'Color','blue')
                hold(app.AXgate2,'on')
                area(app.AXgate2,x,y1,'FaceColor',[0.4940 0.1840 0.5560],'FaceAlpha',0.25,'EdgeAlpha',0.1)
                ylim(app.AXgate2,[-0.1,1.1])
                hold(app.AXgate2,'off')
            else
                cla(app.AXgate2)
            end
        end

        % Value changed function: MS_CP2Button
        function MS_CP2ButtonValueChanged(app, event)
            app.MS_CP2Spinner.Value=-90;
            app.p6=set_position(app.mirB,3,-90 + app.CP2);
        end

        % Value changed function: MS_BP1Spinner
        function MS_BP1SpinnerValueChanged(app, event)
            % Dont click to quickly !
            if app.MS_BP1Spinner.Value>60
                app.MS_BP1Spinner.Value=60;
                app.p1=set_position(app.mirA,1,app.MS_BP1Spinner.Value+ app.BP1.calibration);
            elseif app.MS_BP1Spinner.Value<-60
                app.MS_BP1Spinner.Value=-60;
                app.p1=set_position(app.mirA,1,app.MS_BP1Spinner.Value+ app.BP1.calibration);
            else
                app.p1=set_position(app.mirA,1,app.MS_BP1Spinner.Value+ app.BP1.calibration);
            end
            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            app.MS_SP1.Value = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            SP1 = app.MS_SP1.Value;
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);

            if abs(angle1)>abs(angle2)
                if SP1>LP2
                    app.MS_WidthA.Value = SP1-LP2;
                    app.MS_PeakA.Value = (SP1+LP2)/2;
                else
                    app.MS_WidthA.Value = -404;
                    app.MS_PeakA.Value = -404;
                end
            else
                if SP2>LP1
                    app.MS_WidthA.Value = abs(SP2-LP1);
                    app.MS_PeakA.Value = (SP2+LP1)/2;
                else
                    app.MS_WidthA.Value = -404;
                    app.MS_PeakA.Value = -404;
                end
            end
            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: MS_BP2Spinner
        function MS_BP2SpinnerValueChanged(app, event)
            % Dont click to quickly !
            if app.MS_BP2Spinner.Value>60
                app.MS_BP2Spinner.Value=60;
                app.p2=set_position(app.mirA,2,app.MS_BP2Spinner.Value+ app.BP2.calibration);
            elseif app.MS_BP2Spinner.Value<-60
                app.MS_BP2Spinner.Value=-60;
                app.p2=set_position(app.mirA,2,app.MS_BP2Spinner.Value+ app.BP2.calibration);
            else
                app.p2=set_position(app.mirA,2,app.MS_BP2Spinner.Value+ app.BP2.calibration);
            end
            angle1 = app.MS_BP1Spinner.Value;
            angle2 = app.MS_BP2Spinner.Value;
            app.MS_LP2.Value = app.BP2.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffLP)^2);
            SP1 = app.BP1.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffSP)^2);
            LP1 = app.BP1.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP1.neffLP)^2);
            SP2 = app.BP2.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP2.neffSP)^2);
            LP2 = app.MS_LP2.Value;
            if abs(angle1)>abs(angle2)
                if SP1>LP2
                    app.MS_WidthA.Value = SP1-LP2;
                    app.MS_PeakA.Value = (SP1+LP2)/2;
                else
                    app.MS_WidthA.Value = -404;
                    app.MS_PeakA.Value = -404;
                end
            else
                if SP2>LP1
                    app.MS_WidthA.Value = abs(SP2-LP1);
                    app.MS_PeakA.Value = (SP2+LP1)/2;
                else
                    app.MS_WidthA.Value = -404;
                    app.MS_PeakA.Value = -404;
                end
            end

            graph(app,app.AXgate,SP1,LP1,SP2,LP2)
        end

        % Value changed function: MS_SP3
        function MS_SP3ValueChanged2(app, event)

            updateBSP1(app,app.MS_SP3.Value);
            app.MS_BP3Spinner.Value = app.p4-app.BP3.calibration;
            app.MS_CP2Spinner.Value = app.p6-app.CP2;
            app.MS_MaxSP3.Value = num2str(round(app.BP3.x0SP,2));
            app.MS_MinSP3.Value = num2str(round(app.BP3.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP3.neffSP).^2),2));

            app.MS_MaxLP4.Value = num2str(round(app.BP4.x0LP,2));
            app.MS_MinLP4.Value = num2str(round(app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2),2));
            
        end

        % Value changed function: MS_LP4
        function MS_LP4ValueChanged2(app, event)
            updateBLP2(app,app.MS_LP4.Value);
            app.MS_BP4Spinner.Value = app.p4-app.BP3.calibration;
            app.MS_CP2Spinner.Value = app.p6-app.CP2;
            app.MS_MaxSP3.Value = num2str(round(app.BP3.x0SP,2));
            app.MS_MinSP3.Value = num2str(round(app.BP3.x0SP*sqrt(1 - (sin(deg2rad(60)) / app.BP3.neffSP).^2),2));

            app.MS_MaxLP4.Value = num2str(round(app.BP4.x0LP,2));
            app.MS_MinLP4.Value = num2str(round(app.BP4.x0LP*sqrt(1 - (sin(deg2rad(60)) / app.BP4.neffLP).^2),2));
            
        end

        % Value changed function: MS_CP1Spinner
        function MS_CP1SpinnerValueChanged(app, event)
            % Dont click to quickly !

            app.p3=set_position(app.mirA,3,app.MS_CP1Spinner.Value+ app.CP1);
        end

        % Value changed function: MS_BP3Spinner
        function MS_BP3SpinnerValueChanged(app, event)

            % Dont click to quickly ! 
            
            if app.MS_BP3Spinner.Value>60
                app.MS_BP3Spinner.Value=60;
                app.p4=set_position(app.mirB,1,app.MS_BP3Spinner.Value+ app.BP3.calibration);
            elseif app.MS_BP3Spinner.Value<-60
                app.MS_BP3Spinner.Value=-60;
                app.p4=set_position(app.mirB,1,app.MS_BP3Spinner.Value+ app.BP3.calibration);
            else
                app.p4=set_position(app.mirB,1,app.MS_BP3Spinner.Value+ app.BP3.calibration);
            end
            
            angle1 = app.MS_BP3Spinner.Value;
            angle2 = app.MS_BP4Spinner.Value;
            app.MS_SP3.Value = app.BP3.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffSP)^2);
            SP3 = app.MS_SP3.Value;
            LP3 = app.BP3.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffLP)^2);
            SP4 = app.BP4.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffSP)^2);
            LP4 = app.BP4.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffLP)^2);
            if abs(angle1)>abs(angle2)
                if SP3>LP4
                    app.MS_WidthB.Value = abs(SP3-LP4);
                    app.MS_PeakB.Value = (SP3+LP4)/2;
                else
                    app.MS_WidthB.Value = -404;
                    app.MS_PeakB.Value = -404;
                end
            else
                if SP4>LP3
                    app.MS_WidthB.Value = abs(SP4-LP3);
                    app.MS_PeakB.Value = (SP4+LP3)/2;
                else
                    app.MS_WidthB.Value = -404;
                    app.MS_PeakB.Value = -404;
                end
            end
            graph(app,app.AXgate2,SP3,LP3,SP4,LP4)
        end

        % Value changed function: MS_BP4Spinner
        function MS_BP4SpinnerValueChanged(app, event)

            % Dont click to quickly ! 
            
            if app.MS_BP4Spinner.Value>60
                app.MS_BP4Spinner.Value=60;
                app.p5=set_position(app.mirB,2,app.MS_BP4Spinner.Value+ app.BP4.calibration);
            elseif app.MS_BP4Spinner.Value<-60
                app.MS_BP4Spinner.Value=-60;
                app.p5=set_position(app.mirB,2,app.MS_BP4Spinner.Value+ app.BP4.calibration);
            else
                app.p5=set_position(app.mirB,2,app.MS_BP4Spinner.Value+ app.BP4.calibration);
            end
            angle1 = app.MS_BP3Spinner.Value;
            angle2 = app.MS_BP4Spinner.Value;
            app.MS_LP4.Value = app.BP4.x0LP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffLP)^2);
            SP3 = app.BP3.x0SP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffSP)^2);
            LP3 = app.BP3.x0LP * sqrt(1 - (sin(deg2rad(angle1)) / app.BP3.neffLP)^2);
            SP4 = app.BP4.x0SP * sqrt(1 - (sin(deg2rad(angle2)) / app.BP4.neffSP)^2);
            LP4 = app.MS_LP4.Value;
            if abs(angle1)>abs(angle2)
                if SP3>LP4
                    app.MS_WidthB.Value = abs(SP3-LP4);
                    app.MS_PeakB.Value = (SP3+LP4)/2;
                else
                    app.MS_WidthB.Value = -404;
                    app.MS_PeakB.Value = -404;
                end
            else
                if SP4>LP3
                    app.MS_WidthB.Value = abs(SP4-LP3);
                    app.MS_PeakB.Value = (SP4+LP3)/2;
                else
                    app.MS_WidthB.Value = -404;
                    app.MS_PeakB.Value = -404;
                end
            end
            graph(app,app.AXgate2,SP3,LP3,SP4,LP4)
            
        end

        % Value changed function: MS_CP2Spinner
        function MS_CP2SpinnerValueChanged(app, event)
            % Dont click to quickly !
            app.p6=set_position(app.mirB,3,app.MS_CP2Spinner.Value+ app.CP2);
        end

        % Button pushed function: SetBackgroundButton
        function SetBackgroundButtonPushed(app, event)

            app.p1=set_position(app.mirA,1,-90 + app.BP1.calibration);
            app.p2=set_position(app.mirA,2,-90 + app.BP2.calibration);
            app.p3=set_position(app.mirA,3,-90 + app.CP1);
            app.p4=set_position(app.mirB,1,-90 + app.BP3.calibration);
            app.p5=set_position(app.mirB,2,-90 + app.BP4.calibration);
            app.p6=set_position(app.mirB,3,-90 + app.CP2);

            % Crée une fenêtre popup avec un message et un bouton OK
            h = msgbox('Cacher le capteur afin de mesurer le Background puis cliquez sur OK pour continuer.', 'set Background');
            
            % Attend que l'utilisateur clique sur OK pour fermer la fenêtre
            uiwait(h);

            [x,app.ybackground] = mesure(app);
            cla(app.AXcalib,'reset')
            plot(app.AXcalib,x,app.ybackground)
            xlabel(app.AXcalib, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(app.AXcalib, 'Intensity', 'Interpreter', 'latex')
            title('Spectre background')
        end

        % Button pushed function: SetSourceLEDButton
        function SetSourceLEDButtonPushed(app, event)
            app.p1=set_position(app.mirA,1,-90 + app.BP1.calibration);
            app.p2=set_position(app.mirA,2,-90 + app.BP2.calibration);
            app.p3=set_position(app.mirA,3,-90 + app.CP1);
            app.p4=set_position(app.mirB,1,-90 + app.BP3.calibration);
            app.p5=set_position(app.mirB,2,-90 + app.BP4.calibration);
            app.p6=set_position(app.mirB,3,-90 + app.CP2);

            % Crée une fenêtre popup avec un message et un bouton OK
            h = msgbox('Cacher le capteur afin de mesurer le spectre de la source puis cliquez sur OK pour continuer.', 'set source');
            
            % Attend que l'utilisateur clique sur OK pour fermer la fenêtre
            uiwait(h);

            [x,app.yLED] = mesure(app);
            cla(app.AXcalib,'reset')
            plot(app.AXcalib,x,app.yLED)
            xlabel(app.AXcalib, '$\lambda$ nm', 'Interpreter', 'latex')
            ylabel(app.AXcalib, 'Intensity', 'Interpreter', 'latex')
            title('Spectre Source')
        end

        % Value changed function: SpectroSwitch
        function SpectroSwitchValueChanged(app, event)
            imshow('loading.png', 'Parent', app.AXcalib)
            title(app.AXcalib,'Chargement du spectro')
            pause(2)
            app.Instr = SpectroInit(app);
            cla(app.AXcalib)
            imshow('arrow.png', 'Parent', app.AXcalib)
            title(app.AXcalib,'Chargement terminé')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MoveSystemTab = uitab(app.TabGroup);
            app.MoveSystemTab.Title = 'Move System';
            app.AXgate = uiaxes(app.MoveSystemTab);
            title(app.AXgate, 'Fonction de Transfert Ligne A')
            xlabel(app.AXgate, 'wavelength \lambda (nm)')
            ylabel(app.AXgate, 'Transmitance')
            zlabel(app.AXgate, 'Z')
            app.AXgate2 = uiaxes(app.MoveSystemTab);
            title(app.AXgate2, 'Fonction de Transfert Ligne B')
            xlabel(app.AXgate2, 'wavelength \lambda (nm)')
            ylabel(app.AXgate2, 'Transmitance')
            zlabel(app.AXgate2, 'Z')
            app.LigneAPanel_4 = uipanel(app.MoveSystemTab);
            app.LigneAPanel_4.Title = 'Ligne A';
            app.RotationdesfiltresPanel_3 = uipanel(app.LigneAPanel_4);
            app.RotationdesfiltresPanel_3.Title = 'Rotation des filtres';
            app.BP1Spinner_2Label = uilabel(app.RotationdesfiltresPanel_3);
            app.BP1Spinner_2Label.Text = 'BP1 (°)';
            app.MS_BP1Spinner = uispinner(app.RotationdesfiltresPanel_3);
            app.MS_BP1Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_BP1SpinnerValueChanged, true);
            app.MS_BP1Spinner.Value = 44.8762;
            app.MS_BP2Button = uibutton(app.RotationdesfiltresPanel_3, 'state');
            app.MS_BP2Button.ValueChangedFcn = createCallbackFcn(app, @MS_BP2ButtonValueChanged, true);
            app.MS_BP2Button.Text = 'BP2 90°';
            app.MS_CP1Button = uibutton(app.RotationdesfiltresPanel_3, 'state');
            app.MS_CP1Button.ValueChangedFcn = createCallbackFcn(app, @MS_CP1ButtonValueChanged, true);
            app.MS_CP1Button.Text = 'CP1 90°';
            app.BP2Spinner_2Label = uilabel(app.RotationdesfiltresPanel_3);
            app.BP2Spinner_2Label.Text = 'BP2 (°)';
            app.MS_BP2Spinner = uispinner(app.RotationdesfiltresPanel_3);
            app.MS_BP2Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_BP2SpinnerValueChanged, true);
            app.MS_BP2Spinner.Value = -32.4666;
            app.CP1Spinner_2Label = uilabel(app.RotationdesfiltresPanel_3);
            app.CP1Spinner_2Label.Text = 'CP1 (°)';
            app.MS_CP1Spinner = uispinner(app.RotationdesfiltresPanel_3);
            app.MS_CP1Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_CP1SpinnerValueChanged, true);
            app.MS_CP1Spinner.Value = -9.5928;
            app.MS_BP1Button = uibutton(app.RotationdesfiltresPanel_3, 'state');
            app.MS_BP1Button.ValueChangedFcn = createCallbackFcn(app, @MS_BP1ButtonValueChanged, true);
            app.MS_BP1Button.Text = 'BP1 90°';
            app.PeakAnmEditField_2Label = uilabel(app.LigneAPanel_4);
            app.PeakAnmEditField_2Label.Text = 'Peak A (nm) :';
            app.MS_PeakA = uieditfield(app.LigneAPanel_4, 'numeric');
            app.MS_PeakA.ValueChangedFcn = createCallbackFcn(app, @MS_PeakAValueChanged, true);
            app.MS_PeakA.Value = 530;
            app.MS_WidthA = uieditfield(app.LigneAPanel_4, 'numeric');
            app.MS_WidthA.ValueChangedFcn = createCallbackFcn(app, @MS_WidthAValueChanged, true);
            app.MS_WidthA.Value = 2;
            app.WidthAnmEditField_2Label = uilabel(app.LigneAPanel_4);
            app.WidthAnmEditField_2Label.Text = 'Width A (nm) : ';
            app.rglagebornesupborneinfdesfiltresPanel = uipanel(app.LigneAPanel_4);
            app.rglagebornesupborneinfdesfiltresPanel.Title = 'réglage borne sup / borne inf des filtres';
            app.MS_LP2 = uieditfield(app.rglagebornesupborneinfdesfiltresPanel, 'numeric');
            app.MS_LP2.ValueChangedFcn = createCallbackFcn(app, @MS_LP2ValueChanged, true);
            app.MS_LP2.Value = 529;
            app.BorneInfBP2nmLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.BorneInfBP2nmLabel.Text = 'Borne Inf BP2 (nm) :';
            app.MS_SP1 = uieditfield(app.rglagebornesupborneinfdesfiltresPanel, 'numeric');
            app.MS_SP1.ValueChangedFcn = createCallbackFcn(app, @MS_SP1ValueChanged, true);
            app.MS_SP1.Value = 531;
            app.BornesupBP1nmLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.BornesupBP1nmLabel.Text = 'Borne sup BP1 (nm) :';
            app.MaxTextAreaLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.MaxTextAreaLabel.Text = 'Max : ';
            app.MS_MaxSP1 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel);
            app.MS_MaxSP1.Editable = 'off';
            app.MS_MaxSP1.Value = {'575.04'};
            app.MinTextAreaLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.MinTextAreaLabel.Text = 'Min : ';
            app.MS_MinSP1 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel);
            app.MS_MinSP1.Editable = 'off';
            app.MS_MinSP1.Value = {'507.24'};
            app.MaxTextAreaLabel_2 = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.MaxTextAreaLabel_2.Text = 'Max : ';
            app.MS_MaxLP2 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel);
            app.MS_MaxLP2.Editable = 'off';
            app.MS_MaxLP2.Value = {'553.46'};
            app.MinTextAreaLabel_2 = uilabel(app.rglagebornesupborneinfdesfiltresPanel);
            app.MinTextAreaLabel_2.Text = 'Min : ';
            app.MS_MinLP2 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel);
            app.MS_MinLP2.Editable = 'off';
            app.MS_MinLP2.Value = {'487.25'};
            app.LigneBPanel_5 = uipanel(app.MoveSystemTab);
            app.LigneBPanel_5.Title = 'Ligne B';
            app.RotationdesfiltresPanel_4 = uipanel(app.LigneBPanel_5);
            app.RotationdesfiltresPanel_4.Title = 'Rotation des filtres';
            app.BP3Spinner_2Label = uilabel(app.RotationdesfiltresPanel_4);
            app.BP3Spinner_2Label.Text = 'BP3 (°)';
            app.MS_BP3Spinner = uispinner(app.RotationdesfiltresPanel_4);
            app.MS_BP3Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_BP3SpinnerValueChanged, true);
            app.MS_BP4Button = uibutton(app.RotationdesfiltresPanel_4, 'state');
            app.MS_BP4Button.ValueChangedFcn = createCallbackFcn(app, @MS_BP4ButtonValueChanged, true);
            app.MS_BP4Button.Text = 'BP4 90°';
            app.MS_CP2Button = uibutton(app.RotationdesfiltresPanel_4, 'state');
            app.MS_CP2Button.ValueChangedFcn = createCallbackFcn(app, @MS_CP2ButtonValueChanged, true);
            app.MS_CP2Button.Text = 'CP2 90°';
            app.BP4Spinner_2Label = uilabel(app.RotationdesfiltresPanel_4);
            app.BP4Spinner_2Label.Text = 'BP4 (°)';
            app.MS_BP4Spinner = uispinner(app.RotationdesfiltresPanel_4);
            app.MS_BP4Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_BP4SpinnerValueChanged, true);
            app.CP2Spinner_2Label = uilabel(app.RotationdesfiltresPanel_4);
            app.CP2Spinner_2Label.Text = 'CP2 (°)';
            app.MS_CP2Spinner = uispinner(app.RotationdesfiltresPanel_4);
            app.MS_CP2Spinner.ValueChangedFcn = createCallbackFcn(app, @MS_CP2SpinnerValueChanged, true);
            app.MS_BP3Button = uibutton(app.RotationdesfiltresPanel_4, 'state');
            app.MS_BP3Button.ValueChangedFcn = createCallbackFcn(app, @MS_BP3ButtonValueChanged, true);
            app.MS_BP3Button.Text = 'BP3 90°';
            app.MS_PeakB = uieditfield(app.LigneBPanel_5, 'numeric');
            app.MS_PeakB.ValueChangedFcn = createCallbackFcn(app, @MS_PeakBValueChanged, true);
            app.MS_PeakB.Value = 530;
            app.PeakBnmEditFieldLabel_2 = uilabel(app.LigneBPanel_5);
            app.PeakBnmEditFieldLabel_2.Text = 'Peak B (nm) :';
            app.MS_WidthB = uieditfield(app.LigneBPanel_5, 'numeric');
            app.MS_WidthB.ValueChangedFcn = createCallbackFcn(app, @MS_WidthBValueChanged, true);
            app.MS_WidthB.Value = 2;
            app.WidthAnmEditField_3Label_2 = uilabel(app.LigneBPanel_5);
            app.WidthAnmEditField_3Label_2.Text = 'Width B (nm) : ';
            app.rglagebornesupborneinfdesfiltresPanel_2 = uipanel(app.LigneBPanel_5);
            app.rglagebornesupborneinfdesfiltresPanel_2.Title = 'réglage borne sup / borne inf des filtres';
            app.MaxTextAreaLabel_3 = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MaxTextAreaLabel_3.Text = 'Max : ';
            app.MS_MaxSP3 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MS_MaxSP3.Editable = 'off';
            app.MS_MaxSP3.Value = {'575.04'};
            app.MinTextAreaLabel_3 = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MinTextAreaLabel_3.Text = 'Min : ';
            app.MS_MinSP3 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MS_MinSP3.Editable = 'off';
            app.MS_MinSP3.Value = {'507.24'};
            app.MaxTextAreaLabel_4 = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MaxTextAreaLabel_4.Text = 'Max : ';
            app.MS_MaxLP4 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MS_MaxLP4.Editable = 'off';
            app.MS_MaxLP4.Value = {'553.46'};
            app.MinTextAreaLabel_4 = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MinTextAreaLabel_4.Text = 'Min : ';
            app.MS_MinLP4 = uitextarea(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.MS_MinLP4.Editable = 'off';
            app.MS_MinLP4.Value = {'487.25'};
            app.MS_SP3 = uieditfield(app.rglagebornesupborneinfdesfiltresPanel_2, 'numeric');
            app.MS_SP3.ValueChangedFcn = createCallbackFcn(app, @MS_SP3ValueChanged2, true);
            app.MS_SP3.Value = 531;
            app.BornesupBP3nmLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.BornesupBP3nmLabel.Text = 'Borne sup BP3 (nm) :';
            app.MS_LP4 = uieditfield(app.rglagebornesupborneinfdesfiltresPanel_2, 'numeric');
            app.MS_LP4.ValueChangedFcn = createCallbackFcn(app, @MS_LP4ValueChanged2, true);
            app.MS_LP4.Value = 529;
            app.BornesupBP2nmLabel = uilabel(app.rglagebornesupborneinfdesfiltresPanel_2);
            app.BornesupBP2nmLabel.Text = 'Borne sup BP2 (nm) :';
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.AXcalib = uiaxes(app.CalibrationTab);
            title(app.AXcalib, 'Spectre de la calibration')
            xlabel(app.AXcalib, 'wavelength \lambda (nm)')
            ylabel(app.AXcalib, 'intensity')
            zlabel(app.AXcalib, 'Z')
            app.AXcalibA = uiaxes(app.CalibrationTab);
            title(app.AXcalibA, 'Spectre fonction de transfert ligne A')
            xlabel(app.AXcalibA, 'wavelength \lambda (nm)')
            ylabel(app.AXcalibA, 'intensity')
            zlabel(app.AXcalibA, 'Z')
            app.ParamtredecalibrationPanel = uipanel(app.CalibrationTab);
            app.ParamtredecalibrationPanel.Title = 'Paramètre de calibration';
            app.anglededpartLabel = uilabel(app.ParamtredecalibrationPanel);
            app.anglededpartLabel.Text = 'Angle de départ : ';
            app.AnglededpartEditField = uieditfield(app.ParamtredecalibrationPanel, 'numeric');
            app.AnglededpartEditField.ValueChangedFcn = createCallbackFcn(app, @AnglededpartEditFieldValueChanged, true);
            app.StepEditFieldLabel = uilabel(app.ParamtredecalibrationPanel);
            app.StepEditFieldLabel.Text = 'Step : ';
            app.StepEditField = uieditfield(app.ParamtredecalibrationPanel, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Value = 6;
            app.AngledefinLabel = uilabel(app.ParamtredecalibrationPanel);
            app.AngledefinLabel.Text = 'Angle de fin : ';
            app.AngledefinEditField = uieditfield(app.ParamtredecalibrationPanel, 'numeric');
            app.AngledefinEditField.ValueChangedFcn = createCallbackFcn(app, @AngledefinEditFieldValueChanged, true);
            app.AngledefinEditField.Value = 360;
            app.SeuilEditFieldLabel = uilabel(app.ParamtredecalibrationPanel);
            app.SeuilEditFieldLabel.Text = 'Seuil : ';
            app.SeuilEditField = uieditfield(app.ParamtredecalibrationPanel, 'numeric');
            app.SeuilEditField.ValueChangedFcn = createCallbackFcn(app, @SeuilEditFieldValueChanged, true);
            app.SeuilEditField.Value = 0.25;
            app.CalibrationdesFiltresPassesbandePanel = uipanel(app.CalibrationTab);
            app.CalibrationdesFiltresPassesbandePanel.Title = 'Calibration des Filtres Passes-bande';
            app.LigneBPanel_4 = uipanel(app.CalibrationdesFiltresPassesbandePanel);
            app.LigneBPanel_4.Title = 'Ligne B';
            app.LigneAPanel_3 = uipanel(app.CalibrationdesFiltresPassesbandePanel);
            app.LigneAPanel_3.Title = 'Ligne A';
            app.CalibrationBP1Button = uibutton(app.CalibrationdesFiltresPassesbandePanel, 'push');
            app.CalibrationBP1Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrationBP1ButtonPushed, true);
            app.CalibrationBP1Button.Text = 'Calibration BP1';
            app.CalibrationBP2Button = uibutton(app.CalibrationdesFiltresPassesbandePanel, 'push');
            app.CalibrationBP2Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrationBP2ButtonPushed, true);
            app.CalibrationBP2Button.Text = 'Calibration BP2';
            app.CalibrationBP3Button = uibutton(app.CalibrationdesFiltresPassesbandePanel, 'push');
            app.CalibrationBP3Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrationBP3ButtonPushed, true);
            app.CalibrationBP3Button.Text = 'Calibration BP3';
            app.CalibrationBP4Button = uibutton(app.CalibrationdesFiltresPassesbandePanel, 'push');
            app.CalibrationBP4Button.ButtonPushedFcn = createCallbackFcn(app, @CalibrationBP4ButtonPushed, true);
            app.CalibrationBP4Button.Text = 'Calibration BP4';
            app.LigneAPanel = uipanel(app.CalibrationTab);
            app.LigneAPanel.Title = 'Ligne A';
            app.RotationdesfiltresPanel = uipanel(app.LigneAPanel);
            app.RotationdesfiltresPanel.Title = 'Rotation des filtres';
            app.BP1SpinnerLabel = uilabel(app.RotationdesfiltresPanel);
            app.BP1SpinnerLabel.Text = 'BP1 (°)';
            app.calibBP1Spinner = uispinner(app.RotationdesfiltresPanel);
            app.calibBP2Button = uibutton(app.RotationdesfiltresPanel, 'state');
            app.calibBP2Button.ValueChangedFcn = createCallbackFcn(app, @calibBP2ButtonValueChanged, true);
            app.calibBP2Button.Text = 'BP2 90°';
            app.calibCP1Button = uibutton(app.RotationdesfiltresPanel, 'state');
            app.calibCP1Button.ValueChangedFcn = createCallbackFcn(app, @calibCP1ButtonValueChanged, true);
            app.calibCP1Button.Text = 'CP1 90°';
            app.BP2Label = uilabel(app.RotationdesfiltresPanel);
            app.BP2Label.Text = 'BP2 (°)';
            app.calibBP2Spinner = uispinner(app.RotationdesfiltresPanel);
            app.CP1SpinnerLabel = uilabel(app.RotationdesfiltresPanel);
            app.CP1SpinnerLabel.Text = 'CP1 (°)';
            app.calibCP1Spinner = uispinner(app.RotationdesfiltresPanel);
            app.calibBP1Button = uibutton(app.RotationdesfiltresPanel, 'state');
            app.calibBP1Button.ValueChangedFcn = createCallbackFcn(app, @calibBP1ButtonValueChanged, true);
            app.calibBP1Button.Text = 'BP1 90°';
            app.PeakAnmEditFieldLabel = uilabel(app.LigneAPanel);
            app.PeakAnmEditFieldLabel.Text = 'Peak A (nm) :';
            app.calibPeakA = uieditfield(app.LigneAPanel, 'numeric');
            app.calibPeakA.ValueChangedFcn = createCallbackFcn(app, @calibPeakAValueChanged, true);
            app.calibPeakA.Value = 530;
            app.calibWidthA = uieditfield(app.LigneAPanel, 'numeric');
            app.calibWidthA.ValueChangedFcn = createCallbackFcn(app, @calibWidthAValueChanged, true);
            app.calibWidthA.Value = 2;
            app.WidthAnmEditFieldLabel = uilabel(app.LigneAPanel);
            app.WidthAnmEditFieldLabel.Text = 'Width A (nm) : ';
            app.calibSP1 = uieditfield(app.LigneAPanel, 'numeric');
            app.calibSP1.ValueChangedFcn = createCallbackFcn(app, @calibSP1ValueChanged, true);
            app.SPBP1nmEditFieldLabel = uilabel(app.LigneAPanel);
            app.SPBP1nmEditFieldLabel.Text = 'SP BP1 (nm) :';
            app.calibLP2 = uieditfield(app.LigneAPanel, 'numeric');
            app.calibLP2.ValueChangedFcn = createCallbackFcn(app, @calibLP2ValueChanged, true);
            app.LPBP2nmEditFieldLabel = uilabel(app.LigneAPanel);
            app.LPBP2nmEditFieldLabel.Text = 'LP BP2 (nm) :';
            app.maxLabel = uilabel(app.LigneAPanel);
            app.maxLabel.Text = 'max :';
            app.maxEditField = uieditfield(app.LigneAPanel, 'numeric');
            app.maxEditField.Editable = 'off';
            app.minEditFieldLabel = uilabel(app.LigneAPanel);
            app.minEditFieldLabel.Text = 'min :';
            app.minEditField = uieditfield(app.LigneAPanel, 'numeric');
            app.minEditField.Editable = 'off';
            app.LigneBPanel_2 = uipanel(app.CalibrationTab);
            app.LigneBPanel_2.Title = 'Ligne B';
            app.RotationdesfiltresPanel_2 = uipanel(app.LigneBPanel_2);
            app.RotationdesfiltresPanel_2.Title = 'Rotation des filtres';
            app.BP3SpinnerLabel = uilabel(app.RotationdesfiltresPanel_2);
            app.BP3SpinnerLabel.Text = 'BP3 (°)';
            app.calibBP3Spinner = uispinner(app.RotationdesfiltresPanel_2);
            app.calibBP4Button = uibutton(app.RotationdesfiltresPanel_2, 'state');
            app.calibBP4Button.ValueChangedFcn = createCallbackFcn(app, @calibBP4ButtonValueChanged, true);
            app.calibBP4Button.Text = 'BP4 90°';
            app.calibCP2Button = uibutton(app.RotationdesfiltresPanel_2, 'state');
            app.calibCP2Button.ValueChangedFcn = createCallbackFcn(app, @calibCP2ButtonValueChanged, true);
            app.calibCP2Button.Text = 'CP2 90°';
            app.BP4SpinnerLabel = uilabel(app.RotationdesfiltresPanel_2);
            app.BP4SpinnerLabel.Text = 'BP4 (°)';
            app.calibBP4Spinner = uispinner(app.RotationdesfiltresPanel_2);
            app.CP2SpinnerLabel = uilabel(app.RotationdesfiltresPanel_2);
            app.CP2SpinnerLabel.Text = 'CP2 (°)';
            app.calibCP2Spinner = uispinner(app.RotationdesfiltresPanel_2);
            app.calibBP3Button = uibutton(app.RotationdesfiltresPanel_2, 'state');
            app.calibBP3Button.ValueChangedFcn = createCallbackFcn(app, @calibBP3ButtonValueChanged, true);
            app.calibBP3Button.Text = 'BP3 90°';
            app.calibPeakB = uieditfield(app.LigneBPanel_2, 'numeric');
            app.calibPeakB.ValueChangedFcn = createCallbackFcn(app, @calibPeakBValueChanged, true);
            app.calibPeakB.Value = 530;
            app.PeakBnmEditFieldLabel = uilabel(app.LigneBPanel_2);
            app.PeakBnmEditFieldLabel.Text = 'Peak B (nm) :';
            app.calibWidthB = uieditfield(app.LigneBPanel_2, 'numeric');
            app.calibWidthB.ValueChangedFcn = createCallbackFcn(app, @calibWidthBValueChanged, true);
            app.calibWidthB.Value = 2;
            app.WidthBnmLabel = uilabel(app.LigneBPanel_2);
            app.WidthBnmLabel.Text = 'Width B (nm) : ';
            app.calibSP3 = uieditfield(app.LigneBPanel_2, 'numeric');
            app.calibSP3.ValueChangedFcn = createCallbackFcn(app, @calibSP3ValueChanged, true);
            app.SPBP3nmEditFieldLabel = uilabel(app.LigneBPanel_2);
            app.SPBP3nmEditFieldLabel.Text = 'SP BP3 (nm) :';
            app.calibLP4 = uieditfield(app.LigneBPanel_2, 'numeric');
            app.calibLP4.ValueChangedFcn = createCallbackFcn(app, @calibLP4ValueChanged, true);
            app.LPBP4nmEditFieldLabel = uilabel(app.LigneBPanel_2);
            app.LPBP4nmEditFieldLabel.Text = 'LP BP4 (nm) :';
            app.SetBackgroundButton = uibutton(app.CalibrationTab, 'push');
            app.SetBackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SetBackgroundButtonPushed, true);
            app.SetBackgroundButton.Text = 'Set Background';
            app.SetSourceLEDButton = uibutton(app.CalibrationTab, 'push');
            app.SetSourceLEDButton.ButtonPushedFcn = createCallbackFcn(app, @SetSourceLEDButtonPushed, true);
            app.SetSourceLEDButton.Text = 'Set Source (LED)';
            app.SpectroSwitchLabel = uilabel(app.CalibrationTab);
            app.SpectroSwitchLabel.Text = 'Spectro';
            app.SpectroSwitch = uiswitch(app.CalibrationTab, 'toggle');
            app.SpectroSwitch.ValueChangedFcn = createCallbackFcn(app, @SpectroSwitchValueChanged, true);
            app.ApplicationpourlefiltrageLabel = uilabel(app.UIFigure);
            app.ApplicationpourlefiltrageLabel.Text = 'Application pour le filtrage';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnatoleFiltrageApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end